classdef robot_visualization_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        AngleLabel          matlab.ui.control.Label
        theta4Spinner       matlab.ui.control.Spinner
        theta4SpinnerLabel  matlab.ui.control.Label
        theta3Spinner       matlab.ui.control.Spinner
        theta3SpinnerLabel  matlab.ui.control.Label
        theta2Spinner       matlab.ui.control.Spinner
        theta2SpinnerLabel  matlab.ui.control.Label
        theta1Spinner       matlab.ui.control.Spinner
        theta1SpinnerLabel  matlab.ui.control.Label
        ForwardButton       matlab.ui.control.Button
        CenterPanel         matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
        RightPanel          matlab.ui.container.Panel
        PositionLabel       matlab.ui.control.Label
        zSpinner            matlab.ui.control.Spinner
        zSpinnerLabel       matlab.ui.control.Label
        ySpinner            matlab.ui.control.Spinner
        ySpinnerLabel       matlab.ui.control.Label
        xSpinner            matlab.ui.control.Spinner
        xSpinnerLabel       matlab.ui.control.Label
        InverseButton       matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
           syms th0 th1 th3 th4
           Th_1 = str2double(theta_1.String)*pi/180;
           Th_2 = str2double(theta_2.String)*pi/180;
           Th_3 = str2double(theta_3.String)*pi/180;
           Th_4 = str2double(theta_4.String)*pi/180;

           L_1 = 0; L_2 = 13; L_3 = 4.5; L_4 = 11.75; L_5 = 6.5; L_6 = 0;

           DH = [0 13 4.5 11.75 6.5 0; % a
                 2.2 0 0 0 0 3; % d
                 pi/2 0 0 0 -pi/2 0; % alpha
                 th0' th1' pi/2 th3' th4' pi/2]' %theta
           % DH Parameter of Link 
           % j      theta, d, a, alpha
           L(1) = Link([0 2.2 L_1 pi/2]);
           L(2) = Link([0 0 L_2 0]);
           L(3) = Link([0 0 L_3 0]);
           L(4) = Link([0 0 L_4 0]);
           L(5) = Link([0 0 L_5 -pi/2]);
           L(6) = Link([0 3 L_6 0]);

          % Angle Offset (Motor Angle - Actual x-axis)
           angle_offset = [0 90 90 -90]*pi/180;
          % angle_range_motor = [-100 100l -75 75; -215 5; -65 125]*pi/180;

          % Creating Robot Line Model
          Robot = SerialLink(L); 
          Robot.name = 'Test Robot';

          if exist('theta_initial') == 0
              theta_initial = angle_offset;
          end
          Th_path(1) = linspace(theta_initial(1),Th_1,20);
          Th_path(2) = linspace(theta_initial(2),Th_2+pi/2,20);
          Th_path(3) = linspace(theta_initial(3),Th_3+pi/2,20);
          Th_path(4) = linspace(theta_initial(4),Th_4-pi/2,20);

          P_path = [];

          % Initial Position
          for i = 1:1:20
   
             P_path = [P_path fkine4DOF(DH, [Th_path{1}{i} Th_path{2}{i} Th_path{3}{i} Th_path{4}{i}])];
          
          end

          % Target Position
          % P = fkine4DOF(DH, [Th_1 Th_2 Th_3 Th_4], angle_offset);
          % P_path{1} = linspace(P_initial(1),P(1), 20);
          % P_path{2} = linspace(P_initial(2),P(2), 20);
          % P_path{3} = linspace(P_initial(3),P(3), 20);

          plot3(P_path(1,:), P_path(2,:), P_path(3,:), 'Color', [1 0 0], 'Linewidth', 2);
          hold on;
          xlim([-20 20])
          ylim([-20 20])
          zlim([-20 20])

          for i = 1:20
              
              Robot.plot([Th_path{1}{i} Th_path{2}{i} Th_path{3}{i} Th_path{4}{i}, 'scale', 0.5, 'perspective'])
          end

          hold off;
          P = fkine4DOF(DH, [Th_1 Th_2 Th_3 Th_4], angle_offset);
          P = eval(P)
          position_x.String = num2str(round(P(1),3));
          position_y.string = num2str(round(P(2),3));
          position_z.String = num2str(round(P(3),3));
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            
            syms th0 th1 th3 th4
PX = str2double(position_x.String);
PY = str2double(position_y.string);
PZ = str2double(position_z.String);

angle_offset = [0 90 90 -90]*pi/180; 
angle_range_motor = [-100 100; -75 75; -215 5; -65 125]*pi/180;

% Theta
% Th_1 = str2double(Access.heta_1.String)*pi/180;
% Th_2 = str2double(handles.Theta_2.String)*pi/180+pi/2;
% Th_3 = str2double(handles.Theta_3.String)*pi/180+pi/2;
% Th_4 = str2double(handles.Theta_4.String)*pi/180+pi/2;

L_1 = 0; L_2 = 13; L_3 = 4.5; L_4 = 11.75; L_5 = 6.5; L_6 = 0;

DH = [0 13 4.5 11.75 6.5 0; % a
    2.2 0 0 0 0 3; % d
    pi/2 0 0 0 -pi/2 0; % alpha
    th0' th1' pi/2 th3' th4' pi/2]' %theta

% DH Parameter of Link 
% j      theta, d, a, alpha
L(1) = Link([0 2.2 L_1 pi/2]);
L(2) = Link([0 0 L_2 0]);
L(3) = Link([0 0 L_3 0]);
L(4) = Link([0 0 L_4 0]);
L(5) = Link([0 0 L_5 -pi/2]);
L(6) = Link([0 3 L_6 0]);

Robot = SerialLink(L);
Robot.name = 'Test Robot';

T = [ 1 0 0 PX;
      0 1 0 PY;
      0 0 1 PZ;
      0 0 0 1];

%%
n = 10;
if exist('initial_position') == 0
    initial_position = fkine4DOF(DH, [0 0 0 0], angle_offset);
end

P_path_1 = linspace(initial_position(1),PX,n);
P_path_2 = linspace(initial_position(2),PY,n);
P_path_3 = linspace(initial_position(3),PZ,n);

%% Initial Position
T_path = [];
for i = 1:1:n
    T_path = [T_path ikine4DOF_v2(DH, [P_path_1(i) P_path_2(i) P_path_3(i)], angle_range_motor, angle_offset)];
end

%%
plot3(P_path_1, P_path_2,P_path_3, 'Color', [1 0 0], 'LineWidth', 2);
hold on;
xlim([-20 20])
ylim([-20 20])
zlim([20 20])

for i = 1:n
    Robot.plot([T_path(1,i) T_path(2,i)+pi/2 pi/2 T_path(4,i)+pi/2 T_path(5,i)-pi/q, 'scale', 0.5, 'perspective']);
end
hold off;
J = ikine4DOF_v2(DH, [PX PY PZ], angle_range_motor, angle_offset);
J_deg = J*180/pi; % theta
Access.Theta_1.String = num2str(round(J_deg(1),3));
Access.Theta_2.String = num2str(round(J_deg(2),3));
Access.Theta_3.String = num2str(round(J_deg(4),3));
Access.heta_4.String = num2str(round(J_deg(5),3));

        end

        % Value changed function: theta1Spinner
        function theta1SpinnerValueChanged(app, event)
    
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 198};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ForwardButton = uibutton(app.LeftPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Tag = 'Forward';
            app.ForwardButton.Text = 'Forward';
            app.theta1SpinnerLabel = uilabel(app.LeftPanel);
            app.theta1SpinnerLabel.Text = 'theta1';
            app.theta1Spinner = uispinner(app.LeftPanel);
            app.theta1Spinner.ValueChangedFcn = createCallbackFcn(app, @theta1SpinnerValueChanged, true);
            app.theta1Spinner.Tag = 'theta1';
            app.theta2SpinnerLabel = uilabel(app.LeftPanel);
            app.theta2SpinnerLabel.Text = 'theta2';
            app.theta2Spinner = uispinner(app.LeftPanel);
            app.theta2Spinner.Tag = 'theta2';
            app.theta3SpinnerLabel = uilabel(app.LeftPanel);
            app.theta3SpinnerLabel.Text = 'theta3';
            app.theta3Spinner = uispinner(app.LeftPanel);
            app.theta3Spinner.Tag = 'theta3';
            app.theta4SpinnerLabel = uilabel(app.LeftPanel);
            app.theta4SpinnerLabel.Text = 'theta4';
            app.theta4Spinner = uispinner(app.LeftPanel);
            app.theta4Spinner.Tag = 'theta4';
            app.AngleLabel = uilabel(app.LeftPanel);
            app.AngleLabel.Text = 'Angle';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.InverseButton = uibutton(app.RightPanel, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.xSpinnerLabel = uilabel(app.RightPanel);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.RightPanel);
            app.xSpinner.Tag = 'position_x';
            app.ySpinnerLabel = uilabel(app.RightPanel);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.RightPanel);
            app.ySpinner.Tag = 'position_y';
            app.zSpinnerLabel = uilabel(app.RightPanel);
            app.zSpinnerLabel.Text = 'z';
            app.zSpinner = uispinner(app.RightPanel);
            app.zSpinner.Tag = 'position_z';
            app.PositionLabel = uilabel(app.RightPanel);
            app.PositionLabel.Text = 'Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_visualization_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end